classdef WebCamPixelater < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WebCamPixelaterUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        DropDown                 matlab.ui.control.DropDown
        PixelaterEffectLabel     matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Webcam
        Play = true
    end

    methods (Access = private)
        function processVideo( app )
            while app.Play
                img = snapshot( app.Webcam );
                img = fliplr( img );
                img = imeffects( img, app.DropDown.Value );
                app.UIAxes.Children.CData = img;
                drawnow limitrate
            end
            delete( app )
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup Webcam
            opts = [webcamlist; "Quit"];
            msg = "Please choose from the following options:";
            selection = uiconfirm( app.WebCamPixelaterUIFigure, msg, ...
                "Select Web Cam", Options=opts );
            if (selection == opts(end))
                delete( app )
                return
            else
                app.Webcam = webcam( selection );
            end

            % Setup webcam axes
            img = snapshot( app.Webcam );
            img = fliplr( img );
            imshow( img, Parent=app.UIAxes )
            axis( app.UIAxes, "image")
            disableDefaultInteractivity( app.UIAxes )
            app.UIAxes.Toolbar.Visible = "off";
            app.processVideo(); % start running
        end

        % Close request function: WebCamPixelaterUIFigure
        function WebCamPixelaterUIFigureCloseRequest(app, event)
            % Stop loop before exiting
            app.Play = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WebCamPixelaterUIFigure = uifigure('Visible', 'off');
            app.WebCamPixelaterUIFigure.Name = 'Web Cam Pixelater';
            app.WebCamPixelaterUIFigure.CloseRequestFcn = createCallbackFcn(app, @WebCamPixelaterUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.WebCamPixelaterUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.PixelaterEffectLabel = uilabel(app.GridLayout);
            app.PixelaterEffectLabel.Text = 'Pixelater Effect:';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'none', 'barrel', 'blur', 'edge', 'grayscale', 'negative', 'neon', 'pencil', 'pincushion', 'pixelate', 'quantize', 'wave'};
            app.DropDown.Value = 'none';
            % Show the figure after all components are created
            app.WebCamPixelaterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WebCamPixelater

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.WebCamPixelaterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.WebCamPixelaterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WebCamPixelaterUIFigure)
        end
    end
end